classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        SplitButton            matlab.ui.control.Button
        CropButton             matlab.ui.control.Button
        addcolormapButton      matlab.ui.control.Button
        SaveoutputButton       matlab.ui.control.Button
        resultshowButton       matlab.ui.control.Button
        TextimproveButton      matlab.ui.control.Button
        CompressionButton      matlab.ui.control.Button
        ResizeButton           matlab.ui.control.Button
        RotateButton           matlab.ui.control.Button
        autoadjustmentButton   matlab.ui.control.Button
        distributeButton       matlab.ui.control.Button
        ContrastSlider         matlab.ui.control.Slider
        ContrastSliderLabel    matlab.ui.control.Label
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        makeamaskButton        matlab.ui.control.Button
        convertfromtoButton    matlab.ui.control.Button
        btrinfo                matlab.ui.control.Button
        btr_import             matlab.ui.control.Button
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end


    methods (Access = public)
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x=0;
            setappdata(0,'image_put',x);
            setappdata(0,'result',imread('.\imge_\empty.png'));

        end

        % Button pushed function: btr_import
        function btr_importButtonPushed(app, event)
            [file,path]=uigetfile({'*.bmp;*.jpg;*.png;*.tiff;'},'Select an Image File');
            if isequal(file,0)
                 disp('User selected Cancel');
                  x=0;
                setappdata(0,'image_put',x);
            else
             selectedfile = fullfile(path,file);
             setappdata(0,'selectedfile',selectedfile);
            im=imread(selectedfile);
            imshow(im,'Parent',app.UIAxes_2)
            setappdata(0,'image_read',im);
            x=1;
                setappdata(0,'image_put',x);
            end
        end

        % Button pushed function: btrinfo
        function btrinfoButtonPushed(app, event)
     if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','INFORMATION','error');
     else
         f=getappdata(0,'selectedfile');
            info = imageinfo(f);

              end           

             
        end

        % Button pushed function: convertfromtoButton
        function convertfromtoButtonPushed(app, event)
   
     if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','Convert','error');
     else
         [from,to]=select ();
        im=getappdata(0,'image_read');
        i=rand();
        s=string(i);
        s=s+to;
        imwrite(im,'./out put/'+s)
        MSG = msgbox('Process is DONE','Convert','warn');
     end

        end

        % Button pushed function: makeamaskButton
        function makeamaskButtonPushed(app, event)
     if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','mask','error');
             return;
        
     end
     
    d = dialog('Position',[300 300 250 150],'Name','Mask');
     
    txt = uicontrol('Parent',d,...
           'Style','text',...
           'Position',[20 80 210 40],...
           'String','Select a Number');
       
    popup = uicontrol('Parent',d,...
           'Style','popup',...
           'Position',[75 70 100 25],...
           'String',{2,3,5,9,12},...
           'Callback',@popup_callback);
       
    btn = uicontrol('Parent',d,...
           'Position',[89 20 70 25],...
           'String','OK',...
           'Callback','close');
       
    choice = 0;
     
    % Wait for d to close before running to completion
    uiwait(d);
      
       function popup_callback(popup,event)
         choice = popup.Value;
        str = popup.String;
            int8(choice);
            choice=int8(choice);
       
       end
            im=getappdata(0,'selectedfile');
            int8(choice);
            if choice~=0
                result=mask(choice,im);
                setappdata(0,'result',result);
                imshow(result,'Parent',app.UIAxes)
            end


        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
              VA =value/254;
            
             if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','contrast','error');
             else
             im=getappdata(0,'image_read');
              j = imadjust(im,[VA 1],[]);
               setappdata(0,'result',j);
             imshow(j,'Parent',app.UIAxes)
             end

        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            VA = changingValue
              VA =VA/254;
            
             if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','Convert','error');
             else
                   im=getappdata(0,'image_read');
            
              j = imadjust(im,[0 VA],[]);
                setappdata(0,'result',j);
                imshow(j,'Parent',app.UIAxes); 


             end
        end

        % Button pushed function: distributeButton
        function distributeButtonPushed(app, event)
            if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','distribute','error');
            else
             im=getappdata(0,'image_read');
             result=stretching(im);
               setappdata(0,'result',result);
               imshow(result,'Parent',app.UIAxes);


            end
        end

        % Button pushed function: autoadjustmentButton
        function autoadjustmentButtonPushed(app, event)
              if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','Auto adjustment','error');
              else
    
             im=getappdata(0,'image_read');
             if size(im,3)==3
               result= imadjust(im,[.2 .3 0; .6 .7 1],[]); % constest number 
             else
                result=imadjust(im);
             end
            setappdata(0,'result',result);
            imshow(result,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
             if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','Rotate','error');
             else
                im=getappdata(0,'image_read');
                 global method;
                 global ang;
                 method='nearest';
                 ang=45;
                 % main file
                 f=figure;
                 set(f,'Position',[300 450 550 350],'Color',[.4 .6 .4],'MenuBar','none',...
    'Name','Bio data','Visible','off');
bc = [.4 .6 .4];
%angale
ht = uicontrol('Style','text','Position',[40 220 240 40],...
    'String','angle:','FontSize',20,'FontWeight','bold',...
    'BackgroundColor',bc,'ForegroundColor','w');
hang=uicontrol("Style","slider",'Position',[250 220 180 40], ...
    'Max',180,'Min',-180,'Value',0,'Units','normalized','Callback',{@select_Callback_num});


% medtho
ht1 = uicontrol('Style','text','Position',[60 100 150 40],...
    'String','method:','FontSize',20,'FontWeight','bold',...
    'BackgroundColor',bc,'ForegroundColor','w');

hmethod = uicontrol('style','popupmenu','Position', [250 100 120 40],...
    'BackgroundColor',bc,'FontSize',20,'FontWeight','bold',...
    'ForegroundColor','w','Callback',{@select_Callback_meth});
hmethod.String = {'nearest','bilinear','bicubic'};

close = uicontrol('Style', 'pushbutton', 'String', 'Ok',...
    'Position', [220 50 120 20],...
    'Callback', 'close'); 
movegui(f,'center')
set(f,'Visible','on')
waitfor(hmethod);
waitfor(hang);
waitfor(close);
result=Rotate(im,method,ang);
 setappdata(0,'result',result);
 imshow(result,'Parent',app.UIAxes);
             end
   function select_Callback_meth(src,event)
        val = hmethod.Value;
        str = hmethod.String;
        str{val};
        method=str{val};
   end
   function select_Callback_num(src,event)
       ang= hang.Value;
       tex=string(ang) ;
       ht.String="angle = "+tex;
   end
                      
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
             if getappdata(0,'image_put')~=1
             MSG1 = msgbox('Emptye image please select image','Resize','error');
             else
                im=getappdata(0,'image_read');

                 global scale;
                 scale=45;
                 % main file
                 f=figure;
                 set(f,'Position',[300 450 550 350],'Color',[.4 .6 .4],'MenuBar','none',...
                    'Name','Bio data','Visible','off');
bc = [.4 .6 .4];
%scale
ht = uicontrol('Style','text','Position',[40 220 240 40],...
    'String','scale:','FontSize',20,'FontWeight','bold',...
    'BackgroundColor',bc,'ForegroundColor','w');
hang=uicontrol("Style","slider",'Position',[250 220 180 40], ...
    'Max',500,'Min',0,'Value',100,'Units','normalized','Callback',{@select_Callback_num});


close = uicontrol('Style', 'pushbutton', 'String', 'Ok',...
    'Position', [230 150 200 25],...
    'Callback', 'close'); 

movegui(f,'center')
set(f,'Visible','on')
waitfor(hang);
waitfor(close);
scale =scale/100;
result= imresize(im, scale);
 setappdata(0,'result',result);
 imshow(result,'Parent',app.UIAxes);

             end
            function select_Callback_num(src,event)
       ang= hang.Value;
       tex=string(ang) ;
       ht.String="Scale = "+tex;
   end

        end

        % Button pushed function: CompressionButton
        function CompressionButtonPushed(app, event)
        
            if getappdata(0,'image_put')~=1
                MSG1 = msgbox('Emptye image please select image','Compression','error');
            else
                MSG5 = msgbox('you may lose some information after use this method','warn','warn');
                waitfor(MSG5);


                    im=getappdata(0,'image_read');
                    global qu;
                    global Compression_type;
                    global BitDepth;
                    BitDepth=8;
                    Compression_type='lossy';
                    qu=75;
%input 
f=figure;
set(f,'Position',[300 450 550 350],'Color',[.4 .6 .4],'MenuBar','none',...
    'Name','Bio data','Visible','off');
bc = [.4 .6 .4];
%Compression
ht = uicontrol('Style','text','Position',[40 220 240 40],...
    'String','Quality :','FontSize',20,'FontWeight','bold',...
    'BackgroundColor',bc,'ForegroundColor','w');
hang=uicontrol("Style","slider",'Position',[250 220 180 40], ...
    'Max',100,'Min',0,'Value',100,'Units','normalized','Callback',{@select_Callback_num});

close = uicontrol('Style', 'pushbutton', 'String', 'Ok',...
    'Position', [230 20 200 25],...
    'Callback', 'close'); 
%poppup 1
ht1 = uicontrol('Style','text','Position',[40 100 240 40],...
    'String','Compression :','FontSize',20,'FontWeight','bold',...
    'BackgroundColor',bc,'ForegroundColor','w');
hmethod = uicontrol('style','popupmenu','Position', [250 100 120 40],...
    'BackgroundColor',bc,'FontSize',20,'FontWeight','bold',...
    'ForegroundColor','w','Callback',{@select_Callback_meth});
hmethod.String = {'lossy','lossless'};
%poppup 2
ht1 = uicontrol('Style','text','Position',[40 50 120 40],...
    'String','BitDepth  :','FontSize',20,'FontWeight','bold',...
    'BackgroundColor',bc,'ForegroundColor','w');
hmethod1 = uicontrol('style','popupmenu','Position', [250 50 120 40],...
    'BackgroundColor',bc,'FontSize',20,'FontWeight','bold',...
    'ForegroundColor','w','Callback',{@select_Callback_meth2});
hmethod1.String = {'8','12','16'};
movegui(f,'center')
set(f,'Visible','on')
waitfor(hang);
waitfor(close);
%-------------result ------------------%
        fileN=getappdata(0,'selectedfile');

    imwrite(im,'./out put/out.jpg','Quality',qu,'Mode',Compression_type,'BitDepth',BitDepth);
    result=imread('./out put/out.jpg');
 setappdata(0,'result',result);
 imshow(result,'Parent',app.UIAxes);

             end
            function select_Callback_num(src,event)
       qu= hang.Value;
       tex=string(qu) ;
       ht.String="Quality  = "+tex;

            end

             function select_Callback_meth(src,event)
        Compression_type = hmethod.Value;
        str = hmethod.String;
        Compression_type=str{Compression_type}
             end
                function select_Callback_meth2(src,event)
        BitDepth  = hmethod1.Value;
        str = hmethod1.String;
        BitDepth=str{BitDepth};
        BitDepth=str2double(BitDepth);
             end


        end

        % Button pushed function: TextimproveButton
        function TextimproveButtonPushed(app, event)
            if getappdata(0,'image_put')~=1
                MSG1 = msgbox('Emptye image please select image','Text','error');
            else
                im=getappdata(0,'image_read');
                if size(im,3)==3
                    rgb2gray(im);
                end
                %BW = imbinarize(I,'adaptive',Name,Value)
                global adaptive;
                adaptive='adaptive';
                global Name; %forgraund
                Name ='dark';
                global level;
                level = graythresh(im); % best level of Sensitivity
%input  amke figure
f=figure;
set(f,'Position',[300 450 550 350],'Color',[.4 .6 .4],'MenuBar','none',...
    'Name','Text','Visible','off');
bc = [.4 .6 .4];
%Text
    btrgroup=uibuttongroup('Title','adaptive','Visible','off','BackgroundColor',bc,'Units','pixels', ...
        'Position',[20 200 300 100], ...
        'SelectionChangedFcn',@bselection);
    % Create three radio buttons in the button group.
r1 = uicontrol(btrgroup,'Style',...
                  'radiobutton',...
                  'String','adaptive ',...
                  'Position',[10 50 100 30],...
                  'HandleVisibility','off','BackgroundColor',bc,'ForegroundColor','w');
              
r2 = uicontrol(btrgroup,'Style','radiobutton',...
                  'String','global',...
                  'Position',[10 25 100 30],...
                  'HandleVisibility','off','ForegroundColor','w',BackgroundColor=bc);
%% end of btrgroub
%popuplist
h1 = uicontrol('style','text','Position', [20 150 160 40],...
    'BackgroundColor',bc,'FontSize',12,'FontWeight','bold','String',"Foreground",'ForegroundColor','w');

hmethod1 = uicontrol('style','popupmenu','Position', [250 150 120 40],...
    'BackgroundColor',bc,'FontSize',20,'FontWeight','bold',...
    'ForegroundColor','w','Callback',{@select_Callback_meth2});
hmethod1.String = {'Dark','bright'};
%% end of pupplist
ht = uicontrol('Style','text','Position',[20 100 160 20],...
    'String','level:','FontSize',12,'FontWeight','bold',...
    'BackgroundColor',bc,'ForegroundColor','w');
hang=uicontrol("Style","slider",'Position',[250 100 120 15], ...
    'Max',255,'Min',0,'Value',0,'Units','normalized','Callback',{@select_Callback_num});
%% close 
close = uicontrol('Style', 'pushbutton', 'String', 'Ok',...
    'Position', [190 20 200 25],...
    'Callback', 'close'); 
%% 
movegui(f,'center')
set(f,'Visible','on')
btrgroup.Visible = 'on';
waitfor(close);
%-------------result ------------------%
if adaptive=="adaptive"
    msg2 =msgbox('By using changing the level may be u will get unreadble image recommed let it to auto genert  ','Text','warn');
    waitfor(msg2);
    result=imbinarize(im,'adaptive','ForegroundPolarity',Name,'Sensitivity',level);
else     
    msg1 =msgbox('By using " Globle" we will ignore the level and Foreground valuses ','Text','warn');
    waitfor(msg1);
        result=imbinarize(im,'global');
end
 setappdata(0,'result',result);
 imshow(result,'Parent',app.UIAxes);

            end

            %fun 
             function bselection(source,event)
       adaptive= event.NewValue.String;
    
             end
                  function select_Callback_meth2(src,event)
        Name  = hmethod1.Value;
        str = hmethod1.String;
        Name =str{Name};
                  end
          function select_Callback_num(src,event)

       level= hang.Value;
       tex=string(level) ;
       ht.String="level = "+tex;
       level=level/255;
   end
        end

        % Button pushed function: resultshowButton
        function resultshowButtonPushed(app, event)
            res=getappdata(0,'result');
             
            
             imtool(res);
            
        end

        % Button pushed function: SaveoutputButton
        function SaveoutputButtonPushed(app, event)
            res=getappdata(0,'result');
            if res==imread("imge_\empty.png")
                masg=msgbox("please modfy picuter first","modal","error");
            else
         imshow(res,'Parent',app.UIAxes);
            imsave();
            end
        end

        % Button pushed function: addcolormapButton
        function addcolormapButtonPushed(app, event)
            if getappdata(0,'image_put')~=1
                MSG1 = msgbox('Emptye image please select image','Map','error');
            else
                 global map;
                 map=hot;
                im=getappdata(0,'image_read');
%% input  make figure
f=figure;
set(f,'Position',[300 450 550 350],'Color',[.4 .6 .4],'MenuBar','none',...
    'Name','Text','Visible','off');
bc = [.4 .6 .4];
%% start of pupplist
h1 = uicontrol('style','text','Position', [20 150 160 40],...
    'BackgroundColor',bc,'FontSize',12,'FontWeight','bold','String',"Foreground",'ForegroundColor','w');

hmethod1 = uicontrol('style','popupmenu','Position', [250 150 120 40],...
    'BackgroundColor',bc,'FontSize',20,'FontWeight','bold',...
    'ForegroundColor','w','Callback',{@select_Callback_meth2});
hmethod1.String = {'hot','parula','turbo','hsv','cool','spring','summer','autumn','winter','gray',...
    'bone','copper','pink','jet','flag','white','prism'};
%% close 
close = uicontrol('Style', 'pushbutton', 'String', 'Ok',...
    'Position', [190 20 200 25],...
    'Callback', 'close'); 
%% 
movegui(f,'center')
set(f,'Visible','on')
btrgroup.Visible = 'on';
waitfor(close);
%-------------result ------------------%
result = grs2rgb(im, map);
setappdata(0,'result',result);
 imshow(result,'Parent',app.UIAxes);


            end

              function select_Callback_meth2(src,event)
        map  = hmethod1.Value;
        str = hmethod1.String;
        map =colormap(str{map}) ;
                  end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
              if getappdata(0,'image_put')~=1
                MSG1 = msgbox('Emptye image please select image','Crop','error');
              else
        im=getappdata(0,'image_read');
        imcrop(im);

              end
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
              if getappdata(0,'image_put')~=1
                MSG1 = msgbox('Emptye image please select image','Crop','error');
              else
        im=getappdata(0,'image_read');
        result=imsplit(im);
        setappdata(0,'result',result);
        imshow(result,'Parent',app.UIAxes);


              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'image result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'image ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.btr_import = uibutton(app.GridLayout, 'push');
            app.btr_import.ButtonPushedFcn = createCallbackFcn(app, @btr_importButtonPushed, true);
            app.btr_import.Text = 'select Image';
            app.btrinfo = uibutton(app.GridLayout, 'push');
            app.btrinfo.ButtonPushedFcn = createCallbackFcn(app, @btrinfoButtonPushed, true);
            app.btrinfo.Text = 'show information ';
            app.convertfromtoButton = uibutton(app.GridLayout, 'push');
            app.convertfromtoButton.ButtonPushedFcn = createCallbackFcn(app, @convertfromtoButtonPushed, true);
            app.convertfromtoButton.Text = 'convert from .... to ......';
            app.makeamaskButton = uibutton(app.GridLayout, 'push');
            app.makeamaskButton.ButtonPushedFcn = createCallbackFcn(app, @makeamaskButtonPushed, true);
            app.makeamaskButton.Text = 'make a mask ';
            app.BrightnessSliderLabel = uilabel(app.GridLayout);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayout);
            app.BrightnessSlider.Limits = [1 254];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.Value = 40;
            app.ContrastSliderLabel = uilabel(app.GridLayout);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.GridLayout);
            app.ContrastSlider.Limits = [0 253];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 222;
            app.distributeButton = uibutton(app.GridLayout, 'push');
            app.distributeButton.ButtonPushedFcn = createCallbackFcn(app, @distributeButtonPushed, true);
            app.distributeButton.Text = 'distribute';
            app.autoadjustmentButton = uibutton(app.GridLayout, 'push');
            app.autoadjustmentButton.ButtonPushedFcn = createCallbackFcn(app, @autoadjustmentButtonPushed, true);
            app.autoadjustmentButton.Text = 'auto adjustment';
            app.RotateButton = uibutton(app.GridLayout, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.ResizeButton = uibutton(app.GridLayout, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.CompressionButton = uibutton(app.GridLayout, 'push');
            app.CompressionButton.ButtonPushedFcn = createCallbackFcn(app, @CompressionButtonPushed, true);
            app.CompressionButton.Text = 'Compression';
            app.TextimproveButton = uibutton(app.GridLayout, 'push');
            app.TextimproveButton.ButtonPushedFcn = createCallbackFcn(app, @TextimproveButtonPushed, true);
            app.TextimproveButton.Text = 'Text improve';
            app.resultshowButton = uibutton(app.GridLayout, 'push');
            app.resultshowButton.ButtonPushedFcn = createCallbackFcn(app, @resultshowButtonPushed, true);
            app.resultshowButton.Text = 'result show';
            app.SaveoutputButton = uibutton(app.GridLayout, 'push');
            app.SaveoutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoutputButtonPushed, true);
            app.SaveoutputButton.Text = 'Save output';
            app.addcolormapButton = uibutton(app.GridLayout, 'push');
            app.addcolormapButton.ButtonPushedFcn = createCallbackFcn(app, @addcolormapButtonPushed, true);
            app.addcolormapButton.Text = 'add color map';
            app.CropButton = uibutton(app.GridLayout, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.SplitButton = uibutton(app.GridLayout, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Text = 'Split';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.makeamaskButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end